classdef Hyperspectral < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataAnalysis                   matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AnalysisTypeTab                matlab.ui.container.Tab
        TypeofanalysisButtonGroup      matlab.ui.container.ButtonGroup
        FromrawdataButton              matlab.ui.control.RadioButton
        FromanalyseddataButton         matlab.ui.control.RadioButton
        BrowseButton_4                 matlab.ui.control.Button
        FigurefileEditFieldLabel       matlab.ui.control.Label
        FigurefileEditField            matlab.ui.control.EditField
        BrowseButton_5                 matlab.ui.control.Button
        DatacubeEditFieldLabel         matlab.ui.control.Label
        DatacubeEditField              matlab.ui.control.EditField
        LoaddatafrompreviousanalysisLabel  matlab.ui.control.Label
        ImageCorrections               matlab.ui.container.Tab
        AspectRatio1CheckBox           matlab.ui.control.CheckBox
        FigurePropertiesLabel_2        matlab.ui.control.Label
        RawDataAxis                    matlab.ui.control.UIAxes
        MaxEditFieldLabel              matlab.ui.control.Label
        RawMaxEditField                matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        RawMinEditField                matlab.ui.control.NumericEditField
        NumberofFilesEditFieldLabel    matlab.ui.control.Label
        NumberofFilesEditField         matlab.ui.control.NumericEditField
        CalculateButton                matlab.ui.control.Button
        FigurePropertiesLabel_3        matlab.ui.control.Label
        MaxEditFieldLabel_2            matlab.ui.control.Label
        ProcessedMaxEditField          matlab.ui.control.NumericEditField
        MinEditFieldLabel_2            matlab.ui.control.Label
        ProcessedMinEditField          matlab.ui.control.NumericEditField
        ProcessedDataAxis              matlab.ui.control.UIAxes
        AspectRatio1CheckBox_2         matlab.ui.control.CheckBox
        LampDarkFileEditFieldLabel     matlab.ui.control.Label
        LampDarkFileEditField          matlab.ui.control.EditField
        FilenameBaseEditFieldLabel     matlab.ui.control.Label
        FilenameBaseEditField          matlab.ui.control.EditField
        StepValuescandirDropDownLabel  matlab.ui.control.Label
        StepValuescandirDropDown       matlab.ui.control.DropDown
        CalculateButton_2              matlab.ui.control.Button
        LampFileEditFieldLabel         matlab.ui.control.Label
        LampFileEditField              matlab.ui.control.EditField
        MicroscopeSetupDropDownLabel   matlab.ui.control.Label
        MicroscopeSetupDropDown        matlab.ui.control.DropDown
        BrowseButton                   matlab.ui.control.Button
        BrowseButton_2                 matlab.ui.control.Button
        BrowseButton_3                 matlab.ui.control.Button
        FolderPathEditFieldLabel       matlab.ui.control.Label
        FolderPathEditField            matlab.ui.control.EditField
        ExportDropDown                 matlab.ui.control.DropDown
        SaveNameEditField              matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        ExportFigureLabel              matlab.ui.control.Label
        ExportDropDown_2               matlab.ui.control.DropDown
        SaveNameEditField_2            matlab.ui.control.EditField
        SaveButton_2                   matlab.ui.control.Button
        ExportFigureLabel_2            matlab.ui.control.Label
        ExtractRawDataLabel            matlab.ui.control.Label
        PerformDataCorrectionsLabel    matlab.ui.control.Label
        secLabel                       matlab.ui.control.Label
        timeEditField_4Label_2         matlab.ui.control.Label
        timeEditField                  matlab.ui.control.NumericEditField
        secLabel_6                     matlab.ui.control.Label
        timeEditField_4Label_3         matlab.ui.control.Label
        timeEditField_2                matlab.ui.control.NumericEditField
        ParticleDetectionTab           matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        ThresholdEditFieldLabel        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        GuideMapAxis                   matlab.ui.control.UIAxes
        CalculateButton_3              matlab.ui.control.Button
        AspectRatio1CheckBox_3         matlab.ui.control.CheckBox
        FigurePropertiesLabel          matlab.ui.control.Label
        MaxEditFieldLabel_4            matlab.ui.control.Label
        GuideMaxEditField              matlab.ui.control.NumericEditField
        MinEditFieldLabel_4            matlab.ui.control.Label
        GuideMinEditField              matlab.ui.control.NumericEditField
        ExportDropDown_3               matlab.ui.control.DropDown
        SaveNameEditField_3            matlab.ui.control.EditField
        SaveButton_3                   matlab.ui.control.Button
        ExportFigureLabel_3            matlab.ui.control.Label
        MaptypeDropDownLabel           matlab.ui.control.Label
        MaptypeDropDown                matlab.ui.control.DropDown
        IdentifyParticlesLabel         matlab.ui.control.Label
        ExtractSpectraLabel            matlab.ui.control.Label
        SelectParticlesButton          matlab.ui.control.Button
        Label_3                        matlab.ui.control.Label
        ToendselectionprocesspressUpArrowKeywhileonzoommodeLabel  matlab.ui.control.Label
        PressAltcontinuouslytoselectmultiplepointsLabel  matlab.ui.control.Label
        ClearPlotButton                matlab.ui.control.Button
        secLabel_7                     matlab.ui.control.Label
        timeEditField_4Label_4         matlab.ui.control.Label
        timeEditField_3                matlab.ui.control.NumericEditField
        ExportDropDown_4               matlab.ui.control.DropDown
        SaveNameEditField_4            matlab.ui.control.EditField
        SaveButton_4                   matlab.ui.control.Button
        ExportFigureLabel_4            matlab.ui.control.Label
        LegendEditField                matlab.ui.control.EditField
        Inputdesiredparticlesreferencenameseg123etcoptionalLabel  matlab.ui.control.Label
        spectraAxis                    matlab.ui.control.UIAxes
        ChristinaBoukouvalaLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        Newintensity % Description
        Newmaxintensity
        Newminintensity
        xNum
        numSteps
        particles % Description
        islflag % Description
        energyNum % Description
        intensity % Description
        maxintensity % Description
        minintensity % Description
        NewMymtx % Description
        mymtx % Description
        reverse % Description
        lambda % Description
        b % Description
        EditInt % Description
        baseFileName % Description
        ParSpectrum % Description
    end
    
    methods (Access = private)
        
        
        function ysm = ksmooth(app,in,b)
                c=1/(sqrt(2*pi*.37));
                d=2*.37^2;
                [l,w]=size(in);
                x=in(:,1);
                ysm=x;
                
                for k=2:w
                    out=[];
                    y=in(:,k);
                    for i=1:l
                        num=0;
                        den=0;
                        for j=1:l
                            t = (x(i)-x(j))/b;
                            num = num + y(j)*c*exp(-t^2/d);
                            den = den + c*exp(-t^2/d);
                        end
                        out(i,1)= num/den;
                    end
                    
                    ysm=[ysm, out];
                end   
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FolderPathEditField.Value = pwd;
            app.LampFileEditField.Value = char(strcat(num2str(pwd), '/lamp.csv'));
            app.LampDarkFileEditField.Value = char(strcat(num2str(pwd), '/lampdark.csv'));
            app.StepValuescandirDropDown.Value = 'Positive';
            app.reverse = 0;
        end

        % Value changed function: RawMinEditField
        function RawMinEditFieldValueChanged(app, event)
            value = app.RawMinEditField.Value;
            caxis(app.RawDataAxis,[value app.RawMaxEditField.Value]);
        end

        % Value changed function: RawMaxEditField
        function RawMaxEditFieldValueChanged(app, event)
            value = app.RawMaxEditField.Value;
            caxis(app.RawDataAxis,[app.RawMinEditField.Value value]);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            tic
            app.numSteps = app.NumberofFilesEditField.Value;
            baseFileName = app.FilenameBaseEditField.Value;
            app.baseFileName = app.FilenameBaseEditField.Value; % fix that
            app.xNum =1023;
            app.energyNum = 1023;
            app.b = 2;
            
            app.mymtx = zeros(app.xNum, app.numSteps, app.energyNum);
            n = 21:320%1:app.numSteps;
            
%             outpath = char(strcat(app.FolderPathEditField.Value, '\', newName, '.csv'))
%             mkdir[sprintf(strcat(app.FolderPathEditField.Value, '\output', newName, '.csv'))
            
            for l = 1:length(n)
                currentName = char(strcat(baseFileName, {'  '}, num2str(n(l))));
                newName = char(strcat(baseFileName,{'  '},num2str(l)));
                currentPath = char(strcat(app.FolderPathEditField.Value, '\', currentName, '.csv'));
                newPath = char(strcat(app.FolderPathEditField.Value, '\', newName, '.csv'));
                doesexist = exist (currentPath, 'file');
                if doesexist == 2
                    movefile(currentPath,newPath);
                end
            end
            pause
            
            row1 = 4;
            column1 = 1;
            rowlast = row1+app.xNum-1;
            columnlast = column1+app.energyNum-1;
            length(n)
            
            for i = 1:length(n)
                fileName = strcat(app.FolderPathEditField.Value, '\',baseFileName, num2str(i), '.csv')
                data = csvread(fileName,row1,column1,[row1, column1, rowlast, columnlast]);
                app.mymtx(:,i,:) = data(:,:);
            end
            
            lambda = csvread(fileName,row1-1,column1,[row1-1,column1, row1-1,columnlast]);
            app.lambda = lambda;
            save(char(strcat(app.FolderPathEditField.Value, '\',baseFileName, 'lambda.mat')),'lambda')
            app.intensity = squeeze(sum(app.mymtx(:,:,300:900),3)); %two dimensional intensity map
            app.minintensity = min(min(app.intensity));
            app.maxintensity = max(max(app.intensity));
            
            imagesc(app.RawDataAxis,app.intensity);
            axis(app.RawDataAxis,'image');
            colormap(app.RawDataAxis,'jet');
            colorbar(app.RawDataAxis);
            zoom('on');
            
            if app.reverse == 1
                set(app.RawDataAxis,'xdir','reverse')
            end
            
            caxis(app.RawDataAxis,[0 0.5*app.maxintensity]);
            app.RawMinEditField.Value = 0;
            app.RawMaxEditField.Value = 0.5*app.maxintensity;
            
            app.RawMaxEditField.Editable = 'on';
            app.RawMinEditField.Editable = 'on';
            app.AspectRatio1CheckBox.Enable = 'on';
            
            app.ExportDropDown.Enable = 'on';
            app.SaveNameEditField.Editable = 'on';
            app.SaveButton.Enable = 'on';
            
            app.CalculateButton_2.Enable = 'on';
            app.LampFileEditField.Editable = 'on';
            app.LampDarkFileEditField.Editable = 'on';
            
            app.BrowseButton_2.Enable = 'on';
            app.BrowseButton_3.Enable = 'on';
            toc
            app.timeEditField.Value = toc;
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            tic
            thresh = app.ThresholdEditField.Value;
            
            NewbkgdInt = app.Newminintensity +(app.Newmaxintensity - app.Newminintensity) * thresh./100;
            
            app.EditInt =zeros(app.xNum,app.numSteps);
            
            % It is not necessary to set values to zero but it is useful for debugging
            % It can be removed later
            
            for i = 1:app.xNum
                for j = 1:app.numSteps
                    if app.Newintensity(i,j)<= NewbkgdInt %taking into account the spectra 300:900
                        app.EditInt(i,j) = 0;
                    else
                        app.EditInt(i,j) = app.Newintensity(i,j) - NewbkgdInt;
                    end
                end
            end
            
            maxEditInt = max(max(app.EditInt(:,:)));
            
            % clockwise approach
            % Zero values are added around the matrix (4 columns and 4 rows in total) to avoid some edge implications arizing from complex geometries
            
            flag = 0;
            app.islflag = zeros(app.xNum+4,app.numSteps+4);
            P = zeros(8,2);
            int = zeros(app.xNum+4,app.numSteps+4);
            int(3:app.xNum+2,3:app.numSteps+2) = app.EditInt(:,:);
            ScannedLines = 0;
            
            
            for i = 3:app.xNum+2
                
                ScannedLines = ScannedLines +1;
                
                for j = 3:app.numSteps+2
                    
                    %check if intensity is not zero
                    if int(i,j) ~= 0 && app.islflag(i,j) == 0
                        
                        flag = flag + 1;
                        
                        k = i;
                        l = j;
                        kmax = k;
                        kmin = k;
                        lmax = l;
                        lmin = l;
                        
                        app.islflag(k,l) = flag;
                        app.particles(flag).xy(1,:) = [k, l];
                        app.particles(flag).spectra(1,:) = [app.NewMymtx(k-2,l-2,:)];
                        
                        
                        P(1,:) = [k-1,l-1];
                        P(2,:) = [k-1,l];
                        P(3,:) = [k-1,l+1];
                        P(4,:) = [k,l+1];
                        P(5,:) = [k+1,l+1];
                        P(6,:) = [k+1,l];
                        P(7,:) = [k+1,l-1];
                        P(8,:) = [k,l-1];
                        
                        statement1 = true;
                        pixCounter = 1;
                        
                        while statement1 == true
                            
                            pixCounter = pixCounter+1;
                            statement2 = true;
                            ii = 1;
                            while statement2 == true
                                if int(P(ii,1),P(ii,2)) ~=0
                                    knew = P(ii,1);
                                    lnew = P(ii,2);
                                    app.islflag(knew,lnew) = flag;
                                    app.particles(flag).xy(pixCounter,:) = [knew lnew];
                                    app.particles(flag).spectra(pixCounter,:) = [squeeze(app.NewMymtx(knew-2,lnew-2,:))];
                                    statement2 = false;
                                    ii = 9;
                                end
                                
                                if ii == size(P,1) && int(P(ii,1),P(ii,2)) ==0
                                    statement2 = false;
                                    statement1 = false;
                                end
                                
                                ii = ii + 1;
                                
                                if ii > size(P,1)+1
                                    statement2 = false;
                                end
                                
                            end
                            
                            if [knew,lnew] == [i,j]
                                statement1 = false;
                            end
                            
                            % Boundary conditions
                            if knew == 1 && lnew == 1
                                P(1,:) = [knew,lnew+1];
                                P(2,:) = [knew+1,lnew+1];
                                P(3,:) = [knew+1,l];
                                
                            elseif knew == app.xNum+4 && lnew == app.numSteps+4
                                P(1,:) = [knew,lnew-1];
                                P(2,:) = [knew-1,lnew-1];
                                P(3,:) = [knew-1,lnew];
                                
                            elseif knew == 1 && lnew == app.numSteps+4
                                P(1,:) = [knew+1,lnew];
                                P(2,:) = [knew+1,lnew-1];
                                P(3,:) = [knew,lnew-1];
                                
                            elseif knew == app.xNum+4 && lnew == 1
                                P(1,:) = [knew-1,lnew];
                                P(2,:) = [knew-1,lnew+1];
                                P(3,:) = [knew,lnew+1];
                                
                            elseif knew == 1
                                P(1,:) = [knew,lnew+1];
                                P(2,:) = [knew+1,lnew+1];
                                P(3,:) = [knew+1,lnew];
                                P(4,:) = [knew+1,lnew-1];
                                P(5,:) = [knew,lnew-1];
                                
                            elseif lnew == 1
                                P(1,:) = [knew-1,lnew];
                                P(2,:) = [knew-1,lnew+1];
                                P(3,:) = [knew,lnew+1];
                                P(4,:) = [knew+1,lnew+1];
                                P(5,:) = [knew+1,lnew];
                                
                            elseif knew == app.xNum+4
                                P(1,:) = [knew,lnew-1];
                                P(2,:) = [knew-1,lnew-1];
                                P(3,:) = [knew-1,lnew];
                                P(4,:) = [knew-1,lnew+1];
                                P(5,:) = [knew,lnew+1];
                                
                            elseif lnew == app.numSteps+4
                                P(1,:) = [knew+1,lnew];
                                P(2,:) = [knew+1,lnew-1];
                                P(3,:) = [knew,lnew-1];
                                P(4,:) = [knew-1,lnew-1];
                                P(5,:) = [knew-1,lnew];
                                
                            else
                                
                                if [knew, lnew] == [k-1,l-1]
                                    rot = -6;
                                elseif [knew, lnew] == [k-1,l]
                                    rot = 0;
                                elseif [knew, lnew] == [k-1,l+1]
                                    rot = 0;
                                elseif [knew, lnew] == [k,l+1]
                                    rot = -2;
                                elseif [knew, lnew] == [k+1,l+1]
                                    rot = -2;
                                elseif [knew, lnew] == [k+1,l]
                                    rot = -4;
                                elseif [knew, lnew] == [k+1,l-1]
                                    rot = -4;
                                elseif [knew, lnew] == [k,l-1]
                                    rot = -6;
                                end
                                
                                P(1,:) = [knew-1,lnew-1];
                                P(2,:) = [knew-1,lnew];
                                P(3,:) = [knew-1,lnew+1];
                                P(4,:) = [knew,lnew+1];
                                P(5,:) = [knew+1,lnew+1];
                                P(6,:) = [knew+1,lnew];
                                P(7,:) = [knew+1,lnew-1];
                                P(8,:) = [knew,lnew-1];
                                
                                P = circshift(P,rot);
                                
                            end % corresponds to bounbaries taken into account
                            
                            if knew > kmax
                                kmax = knew;
                            elseif knew <= kmin
                                kmin = knew;
                            end
                            
                            if lnew > lmax
                                lmax = lnew;
                            elseif lnew <= lmin
                                lmin = lnew;
                            end
                            
                            
                            k = knew;
                            l = lnew;
                            
                        end
                        
                        
                        for kk = kmin:kmax
                            statement3 = false;
                            index = 0;
                            for ll = lmin:lmax
                                if app.islflag(kk,ll) == flag
                                    index = index + 1;
                                    col(index) = ll;
                                    statement3 = true;
                                end
                            end
                            
                            while statement3 == true
                                for ll = min(col):max(col)
                                    app.islflag(kk,ll) = flag;
                                    app.particles(flag).xy(pixCounter,:) = [kk ll];
                                    app.particles(flag).spectra(pixCounter,:) = [squeeze(app.NewMymtx(kk-2,ll-2,:))];
                                    app.particles(flag).Newint(pixCounter,:) = app.Newintensity(kk-2,ll-2,:);
                                    pixCounter = pixCounter+1;
                                end
                                
                                if ll == max(col)
                                    statement3 = false;
                                end
                            end
                            clear col
                        end
                        app.particles(flag).pixNum = pixCounter;
                    end
                end
            end
            flag
            
            % figure
            
            %             spectrum = zeros(flag,app.energyNum);
            %             PointNum = 0;
            %             for m = 1:flag
            %                 if app.particles(flag).pixNum <= 400
            %                     PointNum = PointNum + 1;
            %                     sumInt = sum(app.particles(flag).spectra,2);
            %                     compVal = max(sumInt) - (max(sumInt) - min(sumInt))./3;
            %                     spectrum(flag,:) = zeros(1,app.energyNum);
            %                     for n = 1:app.particles(flag).pixNum
            %                         if sumInt(n) > compVal
            %                             spectrum(flag,:) = spectrum(flag,:) + app.particles(flag).spectra(n,:);
            %                         end
            %                     end
            %                 end
            %                 plot(app.lambda,spectrum(flag,:))
            %             end
            
            
            if strcmp( app.MaptypeDropDown.Value,'Black&White') == 1
                
                cla(app.GuideMapAxis)
                imagesc(app.GuideMapAxis,app.islflag);
                axis (app.GuideMapAxis,'image')
                colormap(app.GuideMapAxis,'gray')
                caxis(app.GuideMapAxis, [0 1]);
                zoom('on');
                
                if app.reverse == 1
                    set(app.GuideMapAxis,'xdir','reverse')
                end
                
                app.AspectRatio1CheckBox_3.Enable = 'on';
                app.GuideMinEditField.Editable = 'off';
                app.GuideMaxEditField.Editable = 'off';
                
            elseif strcmp(app.MaptypeDropDown.Value,'Colour') == 1
                
                cla(app.GuideMapAxis)
                imagesc(app.GuideMapAxis,app.EditInt);
                colormap(app.GuideMapAxis,'jet');
                colorbar(app.GuideMapAxis);
                zoom('on');
                
                if app.reverse == 1
                    set(app.GuideMapAxis,'xdir','reverse')
                end
                
                caxis(app.GuideMapAxis,[0 0.5*maxEditInt]);
                app.GuideMinEditField.Value = 0;
                app.GuideMaxEditField.Value = 0.5*maxEditInt;
                
                app.GuideMinEditField.Editable = 'on';
                app.GuideMaxEditField.Editable = 'on';
                app.AspectRatio1CheckBox_3.Enable = 'on';
            end
            
            app.LegendEditField.Editable = 'on';
            app.SelectParticlesButton.Enable = 'on';
            app.ClearPlotButton.Enable = 'on';
            app.SaveNameEditField_3.Editable = 'on';
            app.SaveButton_3.Enable = 'on';
            app.ExportDropDown_3.Enable = 'on';
            toc
            app.timeEditField_3.Value = toc;
        end

        % Value changed function: AspectRatio1CheckBox
        function AspectRatio1CheckBoxValueChanged(app, event)
            value = app.AspectRatio1CheckBox.Value;
            if value == 1
                daspect(app.RawDataAxis,'auto')
                pbaspect(app.RawDataAxis,[1 1 1])
            else
                daspect(app.RawDataAxis,'manual')
                axis(app.RawDataAxis,'image');
            end
        end

        % Value changed function: AspectRatio1CheckBox_2
        function AspectRatio1CheckBox_2ValueChanged(app, event)
            value = app.AspectRatio1CheckBox_2.Value;
            if value == 1
                daspect(app.ProcessedDataAxis,'auto')
                pbaspect(app.ProcessedDataAxis,[1 1 1])
            else
                daspect(app.ProcessedDataAxis,'manual')
                axis(app.ProcessedDataAxis,'image');
            end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            tic
            counterb = 0;
            bkgdInt = app.minintensity +(app.maxintensity - app.minintensity) * 0.001; % I need to be able to choose???
            bkgdSum = zeros(app.xNum,1);
            
            for i = 1:app.xNum
                for j = 1:app.numSteps
                    if app.intensity(i,j) <= bkgdInt %taking into account the spectra 300:900
                        bkgdSum = bkgdSum + squeeze(app.mymtx(i,j,:));
                        counterb = counterb + 1;
                    end
                end
            end
            
            bkgdAv = bkgdSum./counterb;
            bkgdAvg_smoothed = ksmooth(app,[app.lambda; bkgdAv']',app.b);
            bkgdAv = bkgdAvg_smoothed(:,2);
            save(char(strcat(app.FolderPathEditField.Value, '\',app.baseFileName, 'background.mat')),'bkgdAv')
            
            row1 = 4;
            column1 = 1;
            rowlast = row1+app.xNum-1;
            columnlast = column1+app.energyNum-1;
            
            filename = app.LampFileEditField.Value;
            lamp = csvread(filename,row1,column1,[row1, column1, rowlast, columnlast]);
            lamp = squeeze(mean(lamp,1));
            lampData_smoothed = ksmooth(app,[app.lambda; lamp]',app.b);
            lampMtx = lampData_smoothed(:,2);
            save(char(strcat(app.FolderPathEditField.Value, '\',app.baseFileName, 'lamp.mat')),'lampMtx')
            
            filename = app.LampDarkFileEditField.Value;
            dark = csvread(filename,row1,column1,[row1, column1, rowlast, columnlast]);
            dark = squeeze(mean(dark,1));
            darkData_smoothed = ksmooth(app,[app.lambda; dark]',app.b);
            darkMtx = darkData_smoothed(:,2);
            save(char(strcat(app.FolderPathEditField.Value, '\',app.baseFileName, 'lampdark.mat')),'darkMtx')
            
            app.NewMymtx = zeros(app.xNum,app.numSteps,app.energyNum);
            denominator = lampMtx-darkMtx;
            numerator = zeros (app.xNum,app.numSteps,app.energyNum);
            
            for i = 1:app.xNum
                for j = 1:app.numSteps
                    numerator(i,j,:) = (squeeze(app.mymtx(i,j,:))- bkgdAv);
                    %minnum = min(numerator(i,j,:));
                    %if minnum < -10e-8
                    %    numerator(i,j,:) = squeeze(numerator(i,j,:)) - minnum;
                    %end
                    app.NewMymtx(i,j,:) = squeeze(numerator(i,j,:)) ./ denominator;
                end
            end
            
            app.Newintensity = squeeze(sum(app.NewMymtx(:,:,300:900),3));
            app.Newminintensity = min(min(app.Newintensity));
            app.Newmaxintensity = max(max(app.Newintensity));
            
            imagesc(app.ProcessedDataAxis,app.Newintensity)
            axis(app.ProcessedDataAxis,'image');
            colormap(app.ProcessedDataAxis,'jet');
            colorbar(app.ProcessedDataAxis);
            
            if app.reverse == 1
                set(app.ProcessedDataAxis,'xdir','reverse')
            end
            
            caxis(app.ProcessedDataAxis,[0 0.5*app.Newmaxintensity]);
            app.ProcessedMinEditField.Value = 0;
            app.ProcessedMaxEditField.Value = 0.5*app.Newmaxintensity;
            zoom('on');
            
            app.ThresholdEditField.Editable = 'on';
            app.CalculateButton_3.Enable = 'on';
            
            app.ProcessedMinEditField.Editable = 'on';
            app.ProcessedMaxEditField.Editable = 'on';
            app.AspectRatio1CheckBox_2.Enable = 'on';
            
            app.SaveNameEditField_2.Editable = 'on';
            app.SaveButton_2.Enable = 'on';
            app.ExportDropDown_2.Enable = 'on';
            toc
            app.timeEditField_2.Value = toc;
        end

        % Value changed function: AspectRatio1CheckBox_3
        function AspectRatio1CheckBox_3ValueChanged(app, event)
            value = app.AspectRatio1CheckBox_3.Value;
            if value == 1
                daspect(app.GuideMapAxis,'auto')
                pbaspect(app.GuideMapAxis,[1 1 1])
            else
                daspect(app.GuideMapAxis,'manual')
                axis(app.GuideMapAxis,'image');
            end
        end

        % Value changed function: GuideMaxEditField
        function GuideMaxEditFieldValueChanged(app, event)
            value = app.GuideMaxEditField.Value;
            caxis(app.GuideMapAxis,[app.GuideMinEditField.Value value]);
        end

        % Value changed function: GuideMinEditField
        function GuideMinEditFieldValueChanged(app, event)
            value = app.GuideMinEditField.Value;
            caxis(app.GuideMapAxis,[value app.GuideMaxEditField.Value]);
        end

        % Value changed function: ProcessedMinEditField
        function ProcessedMinEditFieldValueChanged(app, event)
            value = app.ProcessedMinEditField.Value;
            caxis(app.ProcessedDataAxis,[value app.ProcessedMaxEditField.Value]);
        end

        % Value changed function: ProcessedMaxEditField
        function ProcessedMaxEditFieldValueChanged(app, event)
            value = app.ProcessedMaxEditField.Value;
            caxis(app.ProcessedDataAxis,[app.ProcessedMinEditField.Value value]);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir;
            app.FolderPathEditField.Value = path;
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [basefilename, folder] = uigetfile('*.csv');
            path = fullfile(folder, basefilename)
            app.LampFileEditField.Value = path;
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            [basefilename, folder] = uigetfile('*.csv');
            path = fullfile(folder, basefilename);
            app.LampDarkFileEditField.Value = path;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            h = figure;
            h.Visible = 'off';
            hAxis = axes(h);
            
            imagesc(hAxis,app.intensity)
            axis(hAxis,'image');
            colormap(hAxis,'jet');
            colorbar(hAxis);
            
            hAxis.YLabel.String = app.RawDataAxis.YLabel.String;
            hAxis.YLabel.FontSize = app.RawDataAxis.YLabel.FontSize;
            hAxis.XLabel.String = app.RawDataAxis.XLabel.String;
            hAxis.XLabel.FontSize = app.RawDataAxis.XLabel.FontSize;
            hAxis.Title.String = app.RawDataAxis.Title.String;
            hAxis.Title.FontSize = app.RawDataAxis.Title.FontSize;
            
            if app.reverse == 1
                set(hAxis,'xdir','reverse')
            end
            
            caxis(hAxis,[app.RawMinEditField.Value app.RawMaxEditField.Value]);
            hAxis.DataAspectRatio = app.RawDataAxis.DataAspectRatio;
            
            % Save as png and fig files.
            if app.ExportDropDown.Value == '.png'
                saveas(h, char(strcat(app.FolderPathEditField.Value,'\', app.SaveNameEditField.Value)), 'png');
            elseif app.ExportDropDown.Value == '.fig'
                savefig(h, char(strcat(app.FolderPathEditField.Value,'\', app.SaveNameEditField.Value)));
            end
            
            % Delete the temporary figure.
            close(h);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            
            h = figure;
            h.Visible = 'off';
            hAxis = axes(h);
            
            imagesc(hAxis,app.Newintensity)
            axis(hAxis,'image');
            colormap(hAxis,'jet');
            colorbar(hAxis);
            
            hAxis.YLabel.String = app.ProcessedDataAxis.YLabel.String;
            hAxis.YLabel.FontSize = app.ProcessedDataAxis.YLabel.FontSize;
            hAxis.XLabel.String = app.ProcessedDataAxis.XLabel.String;
            hAxis.XLabel.FontSize = app.ProcessedDataAxis.XLabel.FontSize;
            hAxis.Title.String = app.ProcessedDataAxis.Title.String;
            hAxis.Title.FontSize = app.ProcessedDataAxis.Title.FontSize;
            
            if app.reverse == 1
                set(hAxis,'xdir','reverse')
            end
            
            caxis(hAxis,[app.ProcessedMinEditField.Value app.ProcessedMaxEditField.Value]);
            hAxis.DataAspectRatio = app.ProcessedDataAxis.DataAspectRatio;
            
            % Save as png and fig files.
            if app.ExportDropDown_2.Value == '.png'
                saveas(h, char(strcat(app.FolderPathEditField.Value,'\', app.SaveNameEditField_2.Value)), 'png');
            elseif app.ExportDropDown_2.Value == '.fig'
                savefig(h, char(strcat(app.FolderPathEditField.Value,'\', app.SaveNameEditField_2.Value)));
            end
            
            % Delete the temporary figure.
            close(h);
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            
            h = figure;
            h.Visible = 'off';
            hAxis = axes(h);
            
            if strcmp(app.MaptypeDropDown.Value,'Black&White') == 1
                imagesc(hAxis,app.islflag)
                axis (hAxis,'image')
                colormap(hAxis,'gray')
                caxis(hAxis, [0 1]);
            elseif strcmp(app.MaptypeDropDown.Value,'Colour') == 1
                imagesc(hAxis,app.EditInt);
                axis (hAxis,'image')
                colormap(hAxis,'jet');
                colorbar(hAxis);
                caxis(hAxis, [app.GuideMinEditField.Value app.GuideMinEditField.Value]);
            end
            
            hAxis.DataAspectRatio = app.GuideMapAxis.DataAspectRatio;
            hAxis.YLabel.String = app.GuideMapAxis.YLabel.String;
            hAxis.YLabel.FontSize = app.GuideMapAxis.YLabel.FontSize;
            hAxis.XLabel.String = app.GuideMapAxis.XLabel.String;
            hAxis.XLabel.FontSize = app.GuideMapAxis.XLabel.FontSize;
            hAxis.Title.String = app.GuideMapAxis.Title.String;
            hAxis.Title.FontSize = app.GuideMapAxis.Title.FontSize;
            
            if app.reverse == 1
                set(hAxis,'xdir','reverse')
            end
            
            % Save as png and fig files.
            if app.ExportDropDown_3.Value == '.png'
                saveas(h, char(strcat(app.FolderPathEditField.Value,'\', app.SaveNameEditField_3.Value)), 'png');
            elseif app.ExportDropDown_3.Value == '.fig'
                savefig(h, char(strcat(app.FolderPathEditField.Value,'\', app.SaveNameEditField_3.Value)));
            end
            
            % Delete the temporary figure.
            close(h);
        end

        % Value changed function: StepValuescandirDropDown
        function StepValuescandirDropDownValueChanged(app, event)
            value = app.StepValuescandirDropDown.Value;
            if strcmp(value,'Positive') == 1
                app.reverse = 0;
            elseif strcmp(value,'Negative') == 1
                app.reverse = 1;
            end
        end

        % Button pushed function: SelectParticlesButton
        function SelectParticlesButtonPushed(app, event)
            h = figure;
            h.Visible = 'on';
            hAxis = axes(h);
            
            imagesc(hAxis,app.islflag)
            axis (hAxis,'image')
            colormap(hAxis,'gray')
            caxis(hAxis, [0 1]);
            
            % DATA CURSOR MODE
            
            %             % Initialize data cursor object
            %             cursorobj = datacursormode(h);
            %             cursorobj.SnapToDataVertex = 'on'; % Snap to our plotted data, on by default
            %
            %             while ~waitforbuttonpress
            %                 % waitforbuttonpress returns 0 with click, 1 with key press
            %                 % Does not trigger on ctrl, shift, alt, caps lock, num lock, or scroll lock
            %                 cursorobj.Enable = 'on'; % Turn on the data cursor, hold alt to select multiple points
            %             end
            %             cursorobj.Enable = 'off';
            %
            %             mypoints = getCursorInfo(cursorobj);
            %
            %             mypointsmat = cell2mat({mypoints.Position}')
            %
            %             xp = mypointsmat(:,1);
            %             yp = mypointsmat(:,2);
            
            % GINPUT OPTION
            
            %             X = []; Y = [];
            %             while 0<1
            %                 [x,y,br] = ginput(1);
            %                 if isempty(br);
            %                     break;
            %                 elseif br==91;
            %                     ax = hAxis; width=ax(2)-ax(1); height=ax(4)-ax(3);
            %                     hAxis([x-width/2 x+width/2 y-height/2 y+height/2]);
            %                     zoom(1/2);
            %                 elseif br==93;
            %                     ax = hAxis; width=ax(2)-ax(1); height=ax(4)-ax(3);
            %                     hAxis([x-width/2 x+width/2 y-height/2 y+height/2]);
            %                     zoom(2);
            %                 else
            %                     X=[X;x];
            %                     Y=[Y;y];
            %                 end;
            %             end
            
            % GETPTS OPTION
            
            X = [];
            Y = [];
            status = 1;
            while status == 1;
                zoom on
                w = waitforbuttonpress;
                value = double(get(gcf,'CurrentCharacter'));
                if w == 1
                    if value == 30;
                        delete(h);
                        status = 0;
                        continue
                    end
                    [xi,yi] = getpts;
                    X = [X;xi];
                    Y = [Y;yi];
                elseif w == 0
                    continue
                end
            end
            
            posj = round(X);
            posi = round(Y);
            
            PointNum = size(X,1);
            flagsOI = zeros(PointNum,1);
            smallaxis = 5;
            largeaxis = 7;
            
            for i = 1:PointNum
                flag = app.islflag(posi(i),posj(i));
                flagsOI(i) = flag;
                islOI.xy  = app.particles(flag).xy;
                sumspectra = zeros(1,1023);
                maxInt = 0;
                for j = 1:size(islOI.xy,1)
                    if app.particles(flag).Newint(j) > maxInt
                        maxInt = app.particles(flag).Newint(j);
                        c10 = app.particles(flag).xy(j,1);
                        c20 = app.particles(flag).xy(j,2);
                    end
                end
                
                sumIntMax = 0;
                for c1 = (c10 - 1.5): 0.5 :(c10 + 1.5)
                    for c2 = (c20 - 1.5): 0.5 :(c20 + 1.5)
                        sumInt = 0;
                        for j = 1:size(islOI.xy,1)
                            elipse = (app.particles(flag).xy(j,1) - c1)/largeaxis + (app.particles(flag).xy(j,2) - c2)/smallaxis;
                            if elipse <= 1
                                sumInt = sumInt + app.particles(flag).Newint(j);
                            end
                        end
                        if sumInt > sumIntMax
                            sumIntMax = sumInt
                            truec1 = c1;
                            truec2 = c2;
                        end
                    end
                end
                
                for j = 1:size(islOI.xy,1) 
                    elipse = (app.particles(flag).xy(j,1) - c1)/largeaxis + (app.particles(flag).xy(j,2) - c2)/smallaxis;
                    if elipse <= 1
                        app.particles(flag).xy(j,1)
                        app.particles(flag).xy(j,2)
                        sumspectra = sumspectra + app.particles(flag).spectra(j,:);
                    end
                end
                ParSpectrum(i,:) = sumspectra;
                plot(app.spectraAxis,app.lambda,sumspectra);
                hold(app.spectraAxis,'on')
            end
            hold(app.spectraAxis,'on')
            app.ParSpectrum = ParSpectrum; 
            
            legendData = strsplit(app.LegendEditField.Value,',');
            if legendData ~= ""
                legend(app.spectraAxis,legendData);
                save(char(strcat(app.FolderPathEditField.Value, '\',app.baseFileName, 'sequence.mat')),'legendData')
                save(char(strcat(app.FolderPathEditField.Value, '\',app.baseFileName, 'spectra.mat')),'ParSpectrum')
            else
                save(char(strcat(app.FolderPathEditField.Value, '\',app.baseFileName, 'spectra.mat')),'ParSpectrum')
            end
           
            app.SaveNameEditField_4.Editable = 'on';
            app.SaveButton_4.Enable = 'on';
            app.ExportDropDown_4.Enable = 'on';
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.spectraAxis)
        end

        % Button pushed function: SaveButton_4
        function SaveButton_4Pushed(app, event)
            h = figure;
            h.Visible = 'off';
            hAxis = axes(h);
            
            plot(hAxis,app.lambda,app.ParSpectrum);
            
            legendData = strsplit(app.LegendEditField.Value,',');
            if legendData ~= ""
                legend(hAxis,legendData);
            end
            
            hAxis.YLabel.String = app.spectraAxis.YLabel.String;
            hAxis.YLabel.FontSize = app.spectraAxis.YLabel.FontSize;
            hAxis.XLabel.String = app.spectraAxis.XLabel.String;
            hAxis.XLabel.FontSize = app.spectraAxis.XLabel.FontSize;
            h.CurrentAxes.Title.String = app.spectraAxis.Title.String;
            h.CurrentAxes.Title.FontSize = app.spectraAxis.Title.FontSize;
            h.CurrentAxes.XLim = app.spectraAxis.XLim;
            h.CurrentAxes.YLim = app.spectraAxis.YLim;
            h.CurrentAxes.ZLim = app.spectraAxis.ZLim;
            
            % Save as png and fig files.
            if app.ExportDropDown_3.Value == '.png'
                saveas(h, char(strcat(app.FolderPathEditField.Value,'\', app.SaveNameEditField_4.Value)), 'png');
            elseif app.ExportDropDown_3.Value == '.fig'
                savefig(h, char(strcat(app.FolderPathEditField.Value,'\', app.SaveNameEditField_4.Value)));
            end
            
            delete(h)
        end

        % Selection changed function: TypeofanalysisButtonGroup
        function AnalysisTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeofanalysisButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataAnalysis = uifigure('Visible', 'off');
            app.DataAnalysis.Name = 'Colour Map';
            app.TabGroup = uitabgroup(app.DataAnalysis);
            app.AnalysisTypeTab = uitab(app.TabGroup);
            app.AnalysisTypeTab.Title = 'Analysis Type';
            app.TypeofanalysisButtonGroup = uibuttongroup(app.AnalysisTypeTab);
            app.TypeofanalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisTypeButtonGroupSelectionChanged, true);
            app.TypeofanalysisButtonGroup.Title = 'Type of analysis';
            app.FromrawdataButton = uiradiobutton(app.TypeofanalysisButtonGroup);
            app.FromrawdataButton.Text = 'From raw data';
            app.FromrawdataButton.Value = true;
            app.FromanalyseddataButton = uiradiobutton(app.TypeofanalysisButtonGroup);
            app.FromanalyseddataButton.Text = 'From analysed data';
            app.BrowseButton_4 = uibutton(app.AnalysisTypeTab, 'push');
            app.BrowseButton_4.Enable = 'off';
            app.BrowseButton_4.Text = 'Browse';
            app.FigurefileEditFieldLabel = uilabel(app.AnalysisTypeTab);
            app.FigurefileEditFieldLabel.Text = 'Figure file';
            app.FigurefileEditField = uieditfield(app.AnalysisTypeTab, 'text');
            app.FigurefileEditField.Editable = 'off';
            app.BrowseButton_5 = uibutton(app.AnalysisTypeTab, 'push');
            app.BrowseButton_5.Enable = 'off';
            app.BrowseButton_5.Text = 'Browse';
            app.DatacubeEditFieldLabel = uilabel(app.AnalysisTypeTab);
            app.DatacubeEditFieldLabel.Text = 'Data cube';
            app.DatacubeEditField = uieditfield(app.AnalysisTypeTab, 'text');
            app.DatacubeEditField.Editable = 'off';
            app.LoaddatafrompreviousanalysisLabel = uilabel(app.AnalysisTypeTab);
            app.LoaddatafrompreviousanalysisLabel.Text = 'Load data from previous analysis';
            app.ImageCorrections = uitab(app.TabGroup);
            app.ImageCorrections.Title = 'Data Corrections';
            app.AspectRatio1CheckBox = uicheckbox(app.ImageCorrections);
            app.AspectRatio1CheckBox.ValueChangedFcn = createCallbackFcn(app, @AspectRatio1CheckBoxValueChanged, true);
            app.AspectRatio1CheckBox.Enable = 'off';
            app.AspectRatio1CheckBox.Text = 'Aspect Ratio 1 ';
            app.FigurePropertiesLabel_2 = uilabel(app.ImageCorrections);
            app.FigurePropertiesLabel_2.Text = 'Figure Properties';
            app.RawDataAxis = uiaxes(app.ImageCorrections);
            title(app.RawDataAxis, 'Raw Data Intensity Map')
            xlabel(app.RawDataAxis, {''; ''})
            ylabel(app.RawDataAxis, {''; ''})
            app.RawDataAxis.XLim = [0 1];
            app.RawDataAxis.YLim = [0 1];
            app.MaxEditFieldLabel = uilabel(app.ImageCorrections);
            app.MaxEditFieldLabel.Text = 'Max';
            app.RawMaxEditField = uieditfield(app.ImageCorrections, 'numeric');
            app.RawMaxEditField.ValueChangedFcn = createCallbackFcn(app, @RawMaxEditFieldValueChanged, true);
            app.RawMaxEditField.Editable = 'off';
            app.MinEditFieldLabel = uilabel(app.ImageCorrections);
            app.MinEditFieldLabel.Text = 'Min';
            app.RawMinEditField = uieditfield(app.ImageCorrections, 'numeric');
            app.RawMinEditField.ValueChangedFcn = createCallbackFcn(app, @RawMinEditFieldValueChanged, true);
            app.RawMinEditField.Editable = 'off';
            app.NumberofFilesEditFieldLabel = uilabel(app.ImageCorrections);
            app.NumberofFilesEditFieldLabel.Text = 'Number of Files';
            app.NumberofFilesEditField = uieditfield(app.ImageCorrections, 'numeric');
            app.CalculateButton = uibutton(app.ImageCorrections, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate ';
            app.FigurePropertiesLabel_3 = uilabel(app.ImageCorrections);
            app.FigurePropertiesLabel_3.Text = 'Figure Properties';
            app.MaxEditFieldLabel_2 = uilabel(app.ImageCorrections);
            app.MaxEditFieldLabel_2.Text = 'Max';
            app.ProcessedMaxEditField = uieditfield(app.ImageCorrections, 'numeric');
            app.ProcessedMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessedMaxEditFieldValueChanged, true);
            app.ProcessedMaxEditField.Editable = 'off';
            app.MinEditFieldLabel_2 = uilabel(app.ImageCorrections);
            app.MinEditFieldLabel_2.Text = 'Min';
            app.ProcessedMinEditField = uieditfield(app.ImageCorrections, 'numeric');
            app.ProcessedMinEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessedMinEditFieldValueChanged, true);
            app.ProcessedMinEditField.Editable = 'off';
            app.ProcessedDataAxis = uiaxes(app.ImageCorrections);
            title(app.ProcessedDataAxis, 'Processed Data Intensity Map')
            ylabel(app.ProcessedDataAxis, {''; ''})
            app.AspectRatio1CheckBox_2 = uicheckbox(app.ImageCorrections);
            app.AspectRatio1CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AspectRatio1CheckBox_2ValueChanged, true);
            app.AspectRatio1CheckBox_2.Enable = 'off';
            app.AspectRatio1CheckBox_2.Text = 'Aspect Ratio 1';
            app.LampDarkFileEditFieldLabel = uilabel(app.ImageCorrections);
            app.LampDarkFileEditFieldLabel.Text = 'Lamp Dark File';
            app.LampDarkFileEditField = uieditfield(app.ImageCorrections, 'text');
            app.LampDarkFileEditField.Editable = 'off';
            app.FilenameBaseEditFieldLabel = uilabel(app.ImageCorrections);
            app.FilenameBaseEditFieldLabel.Text = 'Filename Base';
            app.FilenameBaseEditField = uieditfield(app.ImageCorrections, 'text');
            app.StepValuescandirDropDownLabel = uilabel(app.ImageCorrections);
            app.StepValuescandirDropDownLabel.Text = 'Step Value (scan dir.)';
            app.StepValuescandirDropDown = uidropdown(app.ImageCorrections);
            app.StepValuescandirDropDown.Items = {'Positive', 'Negative'};
            app.StepValuescandirDropDown.ValueChangedFcn = createCallbackFcn(app, @StepValuescandirDropDownValueChanged, true);
            app.StepValuescandirDropDown.Value = 'Positive';
            app.CalculateButton_2 = uibutton(app.ImageCorrections, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Enable = 'off';
            app.CalculateButton_2.Text = 'Calculate';
            app.LampFileEditFieldLabel = uilabel(app.ImageCorrections);
            app.LampFileEditFieldLabel.Text = 'Lamp File';
            app.LampFileEditField = uieditfield(app.ImageCorrections, 'text');
            app.LampFileEditField.Editable = 'off';
            app.MicroscopeSetupDropDownLabel = uilabel(app.ImageCorrections);
            app.MicroscopeSetupDropDownLabel.Text = 'Microscope Setup';
            app.MicroscopeSetupDropDown = uidropdown(app.ImageCorrections);
            app.MicroscopeSetupDropDown.Items = {'M = 40x, step = 0.6'};
            app.MicroscopeSetupDropDown.Value = 'M = 40x, step = 0.6';
            app.BrowseButton = uibutton(app.ImageCorrections, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.ImageCorrections, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Enable = 'off';
            app.BrowseButton_2.Text = 'Browse';
            app.BrowseButton_3 = uibutton(app.ImageCorrections, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Enable = 'off';
            app.BrowseButton_3.Text = 'Browse';
            app.FolderPathEditFieldLabel = uilabel(app.ImageCorrections);
            app.FolderPathEditFieldLabel.Text = 'Folder Path';
            app.FolderPathEditField = uieditfield(app.ImageCorrections, 'text');
            app.ExportDropDown = uidropdown(app.ImageCorrections);
            app.ExportDropDown.Items = {'.fig', '.png'};
            app.ExportDropDown.Value = '.fig';
            app.SaveNameEditField = uieditfield(app.ImageCorrections, 'text');
            app.SaveNameEditField.Editable = 'off';
            app.SaveButton = uibutton(app.ImageCorrections, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ExportFigureLabel = uilabel(app.ImageCorrections);
            app.ExportFigureLabel.Text = 'Export Figure';
            app.ExportDropDown_2 = uidropdown(app.ImageCorrections);
            app.ExportDropDown_2.Items = {'.fig', '.png'};
            app.ExportDropDown_2.Value = '.fig';
            app.SaveNameEditField_2 = uieditfield(app.ImageCorrections, 'text');
            app.SaveNameEditField_2.Editable = 'off';
            app.SaveButton_2 = uibutton(app.ImageCorrections, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Enable = 'off';
            app.SaveButton_2.Text = 'Save';
            app.ExportFigureLabel_2 = uilabel(app.ImageCorrections);
            app.ExportFigureLabel_2.Text = 'Export Figure';
            app.ExtractRawDataLabel = uilabel(app.ImageCorrections);
            app.ExtractRawDataLabel.Text = 'Extract Raw Data';
            app.PerformDataCorrectionsLabel = uilabel(app.ImageCorrections);
            app.PerformDataCorrectionsLabel.Text = 'Perform Data Corrections';
            app.secLabel = uilabel(app.ImageCorrections);
            app.secLabel.Text = 'sec';
            app.timeEditField_4Label_2 = uilabel(app.ImageCorrections);
            app.timeEditField_4Label_2.Text = 'time';
            app.timeEditField = uieditfield(app.ImageCorrections, 'numeric');
            app.secLabel_6 = uilabel(app.ImageCorrections);
            app.secLabel_6.Text = 'sec';
            app.timeEditField_4Label_3 = uilabel(app.ImageCorrections);
            app.timeEditField_4Label_3.Text = 'time';
            app.timeEditField_2 = uieditfield(app.ImageCorrections, 'numeric');
            app.ParticleDetectionTab = uitab(app.TabGroup);
            app.ParticleDetectionTab.Title = 'Particle Detection';
            app.Label = uilabel(app.ParticleDetectionTab);
            app.Label.Text = 'Set such that particles are seen well separated ';
            app.Label_2 = uilabel(app.ParticleDetectionTab);
            app.Label_2.Text = '%';
            app.ThresholdEditFieldLabel = uilabel(app.ParticleDetectionTab);
            app.ThresholdEditFieldLabel.Text = ' Threshold';
            app.ThresholdEditField = uieditfield(app.ParticleDetectionTab, 'numeric');
            app.ThresholdEditField.Editable = 'off';
            app.ThresholdEditField.Value = 3;
            app.GuideMapAxis = uiaxes(app.ParticleDetectionTab);
            title(app.GuideMapAxis, 'Particle Map')
            ylabel(app.GuideMapAxis, {''; ''})
            app.CalculateButton_3 = uibutton(app.ParticleDetectionTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Enable = 'off';
            app.CalculateButton_3.Text = 'Calculate';
            app.AspectRatio1CheckBox_3 = uicheckbox(app.ParticleDetectionTab);
            app.AspectRatio1CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @AspectRatio1CheckBox_3ValueChanged, true);
            app.AspectRatio1CheckBox_3.Enable = 'off';
            app.AspectRatio1CheckBox_3.Text = 'Aspect Ratio 1';
            app.FigurePropertiesLabel = uilabel(app.ParticleDetectionTab);
            app.FigurePropertiesLabel.Text = 'Figure Properties';
            app.MaxEditFieldLabel_4 = uilabel(app.ParticleDetectionTab);
            app.MaxEditFieldLabel_4.Text = 'Max';
            app.GuideMaxEditField = uieditfield(app.ParticleDetectionTab, 'numeric');
            app.GuideMaxEditField.ValueChangedFcn = createCallbackFcn(app, @GuideMaxEditFieldValueChanged, true);
            app.GuideMaxEditField.Editable = 'off';
            app.MinEditFieldLabel_4 = uilabel(app.ParticleDetectionTab);
            app.MinEditFieldLabel_4.Text = 'Min';
            app.GuideMinEditField = uieditfield(app.ParticleDetectionTab, 'numeric');
            app.GuideMinEditField.ValueChangedFcn = createCallbackFcn(app, @GuideMinEditFieldValueChanged, true);
            app.GuideMinEditField.Editable = 'off';
            app.ExportDropDown_3 = uidropdown(app.ParticleDetectionTab);
            app.ExportDropDown_3.Items = {'.fig', '.png'};
            app.ExportDropDown_3.Value = '.fig';
            app.SaveNameEditField_3 = uieditfield(app.ParticleDetectionTab, 'text');
            app.SaveNameEditField_3.Editable = 'off';
            app.SaveButton_3 = uibutton(app.ParticleDetectionTab, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Enable = 'off';
            app.SaveButton_3.Text = 'Save';
            app.ExportFigureLabel_3 = uilabel(app.ParticleDetectionTab);
            app.ExportFigureLabel_3.Text = 'Export Figure';
            app.MaptypeDropDownLabel = uilabel(app.ParticleDetectionTab);
            app.MaptypeDropDownLabel.Text = 'Map type';
            app.MaptypeDropDown = uidropdown(app.ParticleDetectionTab);
            app.MaptypeDropDown.Items = {'Black&White', 'Colour'};
            app.MaptypeDropDown.Value = 'Black&White';
            app.IdentifyParticlesLabel = uilabel(app.ParticleDetectionTab);
            app.IdentifyParticlesLabel.Text = 'Identify Particles';
            app.ExtractSpectraLabel = uilabel(app.ParticleDetectionTab);
            app.ExtractSpectraLabel.Text = 'Extract Spectra';
            app.SelectParticlesButton = uibutton(app.ParticleDetectionTab, 'push');
            app.SelectParticlesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectParticlesButtonPushed, true);
            app.SelectParticlesButton.Enable = 'off';
            app.SelectParticlesButton.Text = 'Select Particles';
            app.Label_3 = uilabel(app.ParticleDetectionTab);
            app.Label_3.Text = 'In the pop-up figure press Enter to switch between zoom mode and selection mode';
            app.ToendselectionprocesspressUpArrowKeywhileonzoommodeLabel = uilabel(app.ParticleDetectionTab);
            app.ToendselectionprocesspressUpArrowKeywhileonzoommodeLabel.Text = 'To end selection process press UpArrow Key while on zoom mode.';
            app.PressAltcontinuouslytoselectmultiplepointsLabel = uilabel(app.ParticleDetectionTab);
            app.PressAltcontinuouslytoselectmultiplepointsLabel.Text = 'Select points with left mouse button. Press Delete to remove last point';
            app.ClearPlotButton = uibutton(app.ParticleDetectionTab, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Enable = 'off';
            app.ClearPlotButton.Text = 'Clear Plot';
            app.secLabel_7 = uilabel(app.ParticleDetectionTab);
            app.secLabel_7.Text = 'sec';
            app.timeEditField_4Label_4 = uilabel(app.ParticleDetectionTab);
            app.timeEditField_4Label_4.Text = 'time';
            app.timeEditField_3 = uieditfield(app.ParticleDetectionTab, 'numeric');
            app.ExportDropDown_4 = uidropdown(app.ParticleDetectionTab);
            app.ExportDropDown_4.Items = {'.fig', '.png'};
            app.ExportDropDown_4.Value = '.fig';
            app.SaveNameEditField_4 = uieditfield(app.ParticleDetectionTab, 'text');
            app.SaveNameEditField_4.Editable = 'off';
            app.SaveButton_4 = uibutton(app.ParticleDetectionTab, 'push');
            app.SaveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_4Pushed, true);
            app.SaveButton_4.Enable = 'off';
            app.SaveButton_4.Text = 'Save';
            app.ExportFigureLabel_4 = uilabel(app.ParticleDetectionTab);
            app.ExportFigureLabel_4.Text = 'Export Figure';
            app.LegendEditField = uieditfield(app.ParticleDetectionTab, 'text');
            app.LegendEditField.Editable = 'off';
            app.Inputdesiredparticlesreferencenameseg123etcoptionalLabel = uilabel(app.ParticleDetectionTab);
            app.Inputdesiredparticlesreferencenameseg123etcoptionalLabel.Text = 'Input desired particles'' reference names e.g. 1,2,3 etc (optional)';
            app.spectraAxis = uiaxes(app.ParticleDetectionTab);
            title(app.spectraAxis, 'Selected Particles'' Spectra')
            xlabel(app.spectraAxis, 'Wavelength (μm)')
            ylabel(app.spectraAxis, 'Intensity')
            app.ChristinaBoukouvalaLabel = uilabel(app.DataAnalysis);
            app.ChristinaBoukouvalaLabel.Text = 'Christina Boukouvala';
            % Show the figure after all components are created
            app.DataAnalysis.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hyperspectral

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataAnalysis)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataAnalysis)
        end
    end
end